classdef iota < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        xEditFieldLabel        matlab.ui.control.Label
        xEditField             matlab.ui.control.NumericEditField
        dxdtEditFieldLabel     matlab.ui.control.Label
        dxdtEditField          matlab.ui.control.NumericEditField
        yEditFieldLabel        matlab.ui.control.Label
        yEditField             matlab.ui.control.NumericEditField
        dydtEditFieldLabel     matlab.ui.control.Label
        dydtEditField          matlab.ui.control.NumericEditField
        zEditFieldLabel        matlab.ui.control.Label
        zEditField             matlab.ui.control.NumericEditField
        dzdtEditFieldLabel     matlab.ui.control.Label
        dzdtEditField          matlab.ui.control.NumericEditField
        EditFieldLabel         matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        PlotButton             matlab.ui.control.StateButton
        t0EditFieldLabel       matlab.ui.control.Label
        t0EditField            matlab.ui.control.NumericEditField
        tfEditFieldLabel       matlab.ui.control.Label
        tfEditField            matlab.ui.control.NumericEditField
        SystemDropDownLabel    matlab.ui.control.Label
        SystemDropDown         matlab.ui.control.DropDown
        ModelDropDownLabel     matlab.ui.control.Label
        ModelDropDown          matlab.ui.control.DropDown
        RUNITEditFieldLabel    matlab.ui.control.Label
        RUNITEditField         matlab.ui.control.NumericEditField
        TUNITEditField_2Label  matlab.ui.control.Label
        TUNITEditField         matlab.ui.control.NumericEditField
        VUNITEditFieldLabel    matlab.ui.control.Label
        VUNITEditField         matlab.ui.control.NumericEditField
        KnobLabel              matlab.ui.control.Label
        Knob                   matlab.ui.control.Knob
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        PrimarySwitchLabel     matlab.ui.control.Label
        PrimarySwitch          matlab.ui.control.Switch
        SecondarySwitchLabel   matlab.ui.control.Label
        SecondarySwitch        matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function [] = plot_orbit(app)
            if app.PlotButton.Value
                x = app.xEditField.Value;
                y = app.yEditField.Value;
                z = app.zEditField.Value;
                dxdt = app.dxdtEditField.Value;
                dydt = app.dydtEditField.Value;
                dzdt = app.dzdtEditField.Value;
                rv0 = [x;y;z;dxdt;dydt;dzdt];
                t0 = app.t0EditField.Value;
                tf = app.tfEditField.Value;
                f = get_model(app);
                [tt,xx] = ode78e(f,t0,tf,rv0,1e-12);
                plot(app.UIAxes, xx(:,1), xx(:,2))
                hold(app.UIAxes,'on')
                plot_planets(app)
                axis(app.UIAxes,"equal")
            end
        end
        
        function [] = plot_planets(app)
            global PRIM SEC RUNIT mu
            [X,Y,Z] = sphere;
            N = length(app.UIAxes.Children);
            if N == 1
                
            end
            for i = 1:N
                if ischar(app.UIAxes.Children(i).UserData)
                    if app.UIAxes.Children(i).UserData == "PRIM"
                        if app.PrimarySwitch.Value == "On"
                            surf(app.UIAxes,PRIM.radius/RUNIT*X - mu,PRIM.radius/RUNIT*Y,PRIM.radius/RUNIT*Z,'UserData',"PRIM")
                            axis(app.UIAxes,"equal")
                        else
                            delete(app.UIAxes.Children(i))
                        end
                    end
                    if app.UIAxes.Children(i).UserData == "SEC"
                        if app.SecondarySwitch.Value == "On"
                            surf(app.UIAxes,SEC.radius/RUNIT*X + 1 - mu,SEC.radius/RUNIT*Y,SEC.radius/RUNIT*Z,'UserData',"SEC")
                            axis(app.UIAxes,"equal")
                        else
                            delete(app.UIAxes.Children(i))
                        end
                    end
                end
            end
                if app.UIAxes.Children(i).UserData == "PRIM"
            end


        end
        
        function [] = update_vals(app)
            global mu RUNIT VUNIT TUNIT
            app.EditField.Value = mu;    
            app.RUNITEditField.Value = RUNIT;
            app.TUNITEditField.Value = VUNIT;
            app.VUNITEditField.Value = TUNIT;
        end
                
        function f = get_model(app)
            value = app.ModelDropDown.Value;
            switch value
                case "CR3BP"
                    f = @(t,x) CR3BP(t,x);
                case "ER3BP"
                    f = @(t,x) ER3BP(t,x);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SystemDropDownValueChanged;
            update_vals(app)
            plot_orbit(app)
        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
            plot_orbit(app)
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: dxdtEditField
        function dxdtEditFieldValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            plot_orbit(app) 
        end

        % Value changed function: dydtEditField
        function dydtEditFieldValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: dzdtEditField
        function dzdtEditFieldValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global mu
            mu = app.EditField.Value;
            app.SystemDropDown.Value = "Other";
            plot_orbit(app)
        end

        % Value changed function: SystemDropDown
        function SystemDropDownValueChanged(app, event)
            value = app.SystemDropDown.Value;
            switch value
                case "Earth/Moon"
                    setEarthMoonGlobal
                case "Jupiter/Europa"
                    seteuropaglobal
                case "Saturn/Enceladus"
                    setenceladusglobal
            end
            update_vals(app)
            plot_orbit(app)
        end

        % Value changed function: t0EditField
        function t0EditFieldValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: tfEditField
        function tfEditFieldValueChanged(app, event)
            plot_orbit(app)
        end

        % Value changed function: RUNITEditField
        function RUNITEditFieldValueChanged(app, event)
            global RUNIT VUNIT TUNIT
            RUNIT = app.RUNITEditField.Value;
            VUNIT = RUNIT/TUNIT;
            app.SystemDropDown.Value = "Other";
        end

        % Value changed function: TUNITEditField
        function TUNITEditFieldValueChanged(app, event)
            global RUNIT VUNIT TUNIT
            TUNIT = app.VUNITEditField.Value;
            VUNIT = RUNIT/TUNIT;
            app.SystemDropDown.Value = "Other";
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            plot_orbit(app)            
        end

        % Value changed function: PrimarySwitch
        function PrimarySwitchValueChanged(app, event)
            value = app.PrimarySwitch.Value;
            plot_planets(app)
        end

        % Value changed function: SecondarySwitch
        function SecondarySwitchValueChanged(app, event)
            value = app.SecondarySwitch.Value;
            plot_planets(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {483, 483};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {423, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 1.00092261924326;
            app.dxdtEditFieldLabel = uilabel(app.LeftPanel);
            app.dxdtEditFieldLabel.Text = 'dx/dt';
            app.dxdtEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dxdtEditField.ValueChangedFcn = createCallbackFcn(app, @dxdtEditFieldValueChanged, true);
            app.dxdtEditField.Value = 1.76211497073916e-15;
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.dydtEditFieldLabel = uilabel(app.LeftPanel);
            app.dydtEditFieldLabel.Text = 'dy/dt';
            app.dydtEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dydtEditField.ValueChangedFcn = createCallbackFcn(app, @dydtEditFieldValueChanged, true);
            app.dydtEditField.Value = -0.00235696714899488;
            app.zEditFieldLabel = uilabel(app.LeftPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Value = 0.00481658661752105;
            app.dzdtEditFieldLabel = uilabel(app.LeftPanel);
            app.dzdtEditFieldLabel.Text = 'dz/dt';
            app.dzdtEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dzdtEditField.ValueChangedFcn = createCallbackFcn(app, @dzdtEditFieldValueChanged, true);
            app.dzdtEditField.Value = 5.44249170986796e-13;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'μ';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 5.44249170986796e-13;
            app.PlotButton = uibutton(app.LeftPanel, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.t0EditFieldLabel = uilabel(app.LeftPanel);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.t0EditField.ValueChangedFcn = createCallbackFcn(app, @t0EditFieldValueChanged, true);
            app.tfEditFieldLabel = uilabel(app.LeftPanel);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tfEditField.ValueChangedFcn = createCallbackFcn(app, @tfEditFieldValueChanged, true);
            app.tfEditField.Value = 10;
            app.SystemDropDownLabel = uilabel(app.LeftPanel);
            app.SystemDropDownLabel.Text = 'System';
            app.SystemDropDown = uidropdown(app.LeftPanel);
            app.SystemDropDown.Items = {'Earth/Moon', 'Jupiter/Europa', 'Saturn/Enceladus', 'Other'};
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemDropDownValueChanged, true);
            app.SystemDropDown.Value = 'Saturn/Enceladus';
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'CR3BP', 'ER3BP', 'CR4BP', 'Ephemeris'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'CR3BP';
            app.RUNITEditFieldLabel = uilabel(app.LeftPanel);
            app.RUNITEditFieldLabel.Text = 'RUNIT';
            app.RUNITEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RUNITEditField.ValueChangedFcn = createCallbackFcn(app, @RUNITEditFieldValueChanged, true);
            app.RUNITEditField.Value = 5.44249170986796e-13;
            app.TUNITEditField_2Label = uilabel(app.LeftPanel);
            app.TUNITEditField_2Label.Text = 'TUNIT';
            app.TUNITEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TUNITEditField.ValueChangedFcn = createCallbackFcn(app, @TUNITEditFieldValueChanged, true);
            app.TUNITEditField.Value = 5.44249170986796e-13;
            app.VUNITEditFieldLabel = uilabel(app.LeftPanel);
            app.VUNITEditFieldLabel.Text = 'VUNIT';
            app.VUNITEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VUNITEditField.Editable = 'off';
            app.VUNITEditField.Value = 5.44249170986796e-13;
            app.KnobLabel = uilabel(app.LeftPanel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.LeftPanel, 'continuous');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X [NON]')
            ylabel(app.UIAxes, 'Y [NON]')
            app.PrimarySwitchLabel = uilabel(app.RightPanel);
            app.PrimarySwitchLabel.Text = 'Primary';
            app.PrimarySwitch = uiswitch(app.RightPanel, 'slider');
            app.PrimarySwitch.ValueChangedFcn = createCallbackFcn(app, @PrimarySwitchValueChanged, true);
            app.PrimarySwitch.Value = 'On';
            app.SecondarySwitchLabel = uilabel(app.RightPanel);
            app.SecondarySwitchLabel.Text = 'Secondary';
            app.SecondarySwitch = uiswitch(app.RightPanel, 'slider');
            app.SecondarySwitch.ValueChangedFcn = createCallbackFcn(app, @SecondarySwitchValueChanged, true);
            app.SecondarySwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iota

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end